classdef adcircAnimator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ADCIRCAnimatorUIFigure      matlab.ui.Figure
        Menu                        matlab.ui.container.Menu
        GridLayout                  matlab.ui.container.GridLayout
        UIAxes                      matlab.ui.control.UIAxes
        LOADButton                  matlab.ui.control.Button
        BrowserButton               matlab.ui.control.Button
        DrawFrameButton             matlab.ui.control.Button
        GOButton                    matlab.ui.control.StateButton
        VectorPropertiesPanel       matlab.ui.container.Panel
        ScaleEditFieldLabel         matlab.ui.control.Label
        ScaleEditField              matlab.ui.control.EditField
        StrideEditFieldLabel        matlab.ui.control.Label
        StrideEditField             matlab.ui.control.EditField
        VectorColorLabel            matlab.ui.control.Label
        VectorColorDropDown         matlab.ui.control.DropDown
        ScalarPropertiesPanel       matlab.ui.container.Panel
        ColormapDropDownLabel       matlab.ui.control.Label
        ColormapDropDown            matlab.ui.control.DropDown
        AdvanceButton               matlab.ui.control.Button
        TimeIndexLabel              matlab.ui.control.Label
        TimeIndexSlider             matlab.ui.control.Slider
        CurrentIndexEditFieldLabel  matlab.ui.control.Label
        CurrentIndexEditField       matlab.ui.control.NumericEditField
        MessagesLabel               matlab.ui.control.Label
        MessagesTextArea            matlab.ui.control.TextArea
        CurrentDirectoryLabel       matlab.ui.control.Label
        CurrentDirectoryTextArea    matlab.ui.control.TextArea
        StatusLabel                 matlab.ui.control.Label
        StatusLamp                  matlab.ui.control.Lamp
        AxisEqualButton             matlab.ui.control.Button
        AxisTightButton             matlab.ui.control.Button
        TimeStrideEditFieldLabel    matlab.ui.control.Label
        TimeStrideEditField         matlab.ui.control.NumericEditField
        GridPanel                   matlab.ui.container.Panel
        ElementsSwitchLabel         matlab.ui.control.Label
        ElementsSwitch              matlab.ui.control.ToggleSwitch
        ZoomOnButton                matlab.ui.control.StateButton
        PanOnButton                 matlab.ui.control.StateButton
    end

    
    properties (Access = public)
        Grid  % Description
        nc63 = 'fort.63.nc'
        nc64 = 'fort.64.nc'
        Url = ''
        ColorMin = []
        ColorMax = []
        ContourVals = 0
        handles = {};
        surfaceVariable = 'zeta'
        vectorVariable_u = 'u-vel'
        vectorVariable_v = 'v-vel'
        nTimes = []
        StartingTime = datetime(0,0,0)
        IterStart  = 1 
        IterStride = 1
        IterStop   = Inf 
        time = [] 
        autoLoad = true
        colormesh2d_handle = [] 
        boundary_handle = [] 
        elements_handle = [] 
        vecplot_handle = []
        colormap = jet(24)
        vector_properties = {}
        handle_zoom = []
        
    end
    
    methods (Access = private)
        
        function results = GetNCs(app)
            app.MessagesTextArea.Value=['Loading nc files in ' pwd];
%            app.nc63
            app.nc63=ncgeodataset(app.nc63);
            app.nc64=ncgeodataset(app.nc64);
%             if isempty(app.Url)
% %                 if isa(app.nc63,'ncgeodataset')
% %                     % already have nc objs.
% %                     disp('Already have ncgeodatasets.  Clear and "Go" again.')
% %                 elseif exist(app.nc63,'file')
%                         app.nc63=ncgeodataset(app.nc63);
%                         app.nc64=ncgeodataset(app.nc64);
% %                 else
%                     error('fort.63,64.nc does not exist locally. Either cd to a directory, or use Url.')
% %                 end
%             else
%                 try
%                     app.nc63=ncgeodataset([app.Url '/fort.63.nc']);
%                     app.nc64=ncgeodataset([app.Url '/fort.64.nc']);
%                 catch ME
%                     disp(['ID: ' ME.identifier])
%                     throw(ME)
%                 end
%             end      
            app.MessagesTextArea.Value='Loaded fort.63.nc and fort.64.nc';
            GetTimes(app)
        end
        
        function results = GetTimes(app)
            app.MessagesTextArea.Value='Getting/setting times.';
            time=app.nc63.time('time'); %#ok<*ADPROP> 
            if isempty(time)
                error('time in fort.63.nc is length 0.  This is pointless.')
            end
            time=datetime(datevec(time));
            app.nTimes=size(time,1);
            t=time;
            if app.StartingTime ~= datetime(0,0,0)
                t=t-t(1);
                t=t+app.StartingTime;
            end
            app.time=t;
            % set the slider min/max indices
            app.TimeIndexSlider.Limits=[0 app.nTimes];
            app.IterStop=app.nTimes;
        end
        
        function results = InitFrame(app)
            app.MessagesTextArea.Value='Initializing frame.';
            %app.fign=figure('Position',app.FigurePosition);
            axis(app.UIAxes,'equal')
            axis(app.UIAxes,'tight')
            SetColormap(app)
            colorbar(app.UIAxes)
            %obj=set_ax_lims(obj);
        end
        
        function SetColormap(app)
            colormap(app.UIAxes,app.colormap);
        end
    
        function results = GetGrid(app)
            app.MessagesTextArea.Value='Getting grid.';
            app.Grid=ExtractGrid(app.nc63);
        end
        
        function PlotBnd(app)
            app.MessagesTextArea.Value='Drawing boundary';
            app.boundary_handle=plotbnd(app.Grid,'Axes',app.UIAxes,'LineWidth',.2,'Color','m','Clipping','on');
        end
        
        function results = DrawContours(app)
            app.MessagesTextArea.Value='Drawing coutours.';
            lcontour(app.Grid,'z',app.ContourVals,'Axes',app.UIAxes,'Color','k','LineWidth',1);
        end
        
        function results = DrawFrame(app)
            % get index in Goto Index
            % SetStatusBusy(app)
            idx=app.CurrentIndexEditField.Value;
            app.MessagesTextArea.Value=string(idx);
            app.colormesh2d_handle = DrawSurf(app);
            app.vecplot_handle = DrawVec(app);
            % SetStatusReady(app)
        end
    
        function h = DrawSurf(app)
            app.MessagesTextArea.Value='Drawing surface.';
            if ishandle(app.colormesh2d_handle)
                delete(app.colormesh2d_handle)
            end
            h=colormesh2d(app.Grid,app.nc63{app.surfaceVariable}(app.CurrentIndexEditField.Value,:),'Axes',app.UIAxes);
            %h=colormesh2d(app.Grid,app.nc64{'v-vel'}(app.CurrentIndexEditField.Value,:),'Axes',app.UIAxes);
            h.Clipping='on';
        end
        
        function h = DrawVec(app)
             app.MessagesTextArea.Value='Drawing vectors.';
             drawnow
             if ishandle(app.vecplot_handle)
                 delete(app.vecplot_handle)
             end
             u=app.nc64{app.vectorVariable_u}(app.CurrentIndexEditField.Value,:);
             u=u(:);
             v=app.nc64{app.vectorVariable_v}(app.CurrentIndexEditField.Value,:);
             v=v(:);
             vsac=str2double(app.ScaleEditField.Value);
             vstr=str2double(app.StrideEditField.Value);
             vcol=app.VectorColorDropDown.Value;
             h=vecplot(app.Grid.x,app.Grid.y,u,v,'Axes',app.UIAxes,'ScaleLabel','no scale','ScaleFac',vsac,'Stride',vstr,'Color',vcol);
             h.Clipping='on';
        end
        
        function results = SetStatusReady(app)
            app.StatusLamp.Color='g';
        end
        
        function results = SetStatusBusy(app)
            app.StatusLamp.Color='r';
            pause(.0001)
        end
        
        function results = Animate(app)
            app.IterStart=app.CurrentIndexEditField.Value;
            app.IterStride=app.TimeStrideEditField.Value;
            SetStatusBusy(app)
            for it =  app.IterStart:app.IterStride:app.IterStop
                app.CurrentIndexEditField.Value=it;
                app.DrawFrame()
                drawnow
            end
            SetStatusReady(app)
        end
        
        function results = DrawElems(app)
            app.elements_handle=drawelems(app.Grid,'Axes',app.UIAxes);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, IterStart)
            %app.ADCIRCAnimatorUIFigure.CreateFcn='';
            %app.UIAxes.Toolbar.Visible = 'off'; 
            %zoom(app.UIAxes,'on');
            [tb,btns] = axtoolbar(app.UIAxes,{'zoomin','zoomout','restoreview','pan'});
            if app.autoLoad
                LOADButtonPushed(app)
            end
        end

        % Button pushed function: LOADButton
        function LOADButtonPushed(app, event)
            SetStatusBusy(app)
            app.MessagesTextArea.Value=sprintf('Loading ADCIRC files in %s.',pwd);
            app.GetNCs()
%             try 
%                 app.MessagesTextArea.Value=sprintf("Loading fort.63,64.nc in %s...",pwd);
%                 app.GetNCs()
%             catch ME
%                 app.MessagesTextArea.Value=sprintf("Current directory = %s.  fort.63,64.nc does not exist locally. Either cd to a directory, or use Url.",pwd);
%                 return
%            end
            cla(app.UIAxes)
            app.GetGrid()
            app.InitFrame()
            app.PlotBnd()
            app.DrawContours()
            app.DrawFrame()
            app.GetTimes()
            app.CurrentDirectoryTextArea.Value=pwd;
            SetStatusReady(app)
        end

        % Button pushed function: BrowserButton
        function BrowserButtonPushed(app, event)
            selpath=uigetdir;
            if exist(selpath,'dir')
                cd(selpath)
            end
            app.MessagesTextArea.Value=pwd;
            % need to reset app and reload
            app.LOADButtonPushed()
        end

        % Value changed function: ElementsSwitch
        function ElementsButtonValueChanged2(app, event)
            SetStatusBusy(app)
            h=findobj(app.UIAxes,'Tag','elements');
            if ishandle(h)
                delete(h)
            else
                app.DrawElems()
            end
            SetStatusReady(app)
        end

        % Callback function: ADCIRCAnimatorUIFigure, 
        % ADCIRCAnimatorUIFigure, DrawFrameButton
        function DrawFrameButtonPushed(app, event)
            disp('inside DrawFrameButtonPushed')
            SetStatusBusy(app)
            app.DrawFrame()
            SetStatusReady(app)
        end

        % Value changed function: CurrentIndexEditField, 
        % TimeIndexSlider
        function TimeSliderIndexEditFieldValueChanged(app, event)
            value = app.TimeIndexSlider.Value;
            app.CurrentIndexEditField.Value=floor(value);
            SetStatusBusy(app)
            app.DrawFrame()
            SetStatusReady(app)
        end

        % Value changed function: GOButton
        function GOButtonValueChanged(app, event)
            value = app.GOButton.Value;
            % if 1, start animation, else stop
            if value
                app.GOButton.BackgroundColor='r';
                app.GOButton.Text='STOP';
                
                app.IterStart=app.CurrentIndexEditField.Value;
                app.IterStride=app.TimeStrideEditField.Value;
                SetStatusBusy(app)
                for it =  app.IterStart:app.IterStride:app.IterStop
                    app.CurrentIndexEditField.Value=it;
                    app.DrawFrame()
                    drawnow
                end
                SetStatusReady(app)
%                app.Animate()
            else
                app.GOButton.BackgroundColor='g';
                app.GOButton.Text='GO';
            end
        end

        % Value changed function: VectorColorDropDown
        function VectorColorDropDownValueChanged(app, event)
            value = app.VectorColorDropDown.Value;
            set(app.vecplot_handle,'Color',value)
        end

        % Button pushed function: AxisEqualButton
        function AxisEqualButtonPushed(app, event)
            axis(app.UIAxes,'equal')
        end

        % Button pushed function: AxisTightButton
        function AxisTightButtonPushed(app, event)
            axis(app.UIAxes,'tight')
        end

        % Button pushed function: AdvanceButton
        function AdvanceButtonPushed(app, event)
            % increment frame
            value = app.CurrentIndexEditField.Value;
            stride=app.TimeStrideEditField.Value;
            app.CurrentIndexEditField.Value=value+stride;
            app.TimeIndexSlider.Value=value+stride;
            app.DrawFrame()
        end

        % Value changed function: ColormapDropDown
        function ColormapDropDownValueChanged(app, event)
            value = app.ColormapDropDown.Value;
            app.colormap=value;
            SetColormap(app)
        end

        % Value changed function: ScaleEditField, StrideEditField
        function ScaleEditFieldValueChanged(app, event)
            % value = app.ScaleEditField.Value;
            % app.VectorPropertiesPanel
            app.vecplot_handle = DrawVec(app);
        end

        % Value changed function: ZoomOnButton
        function ZoomOnButtonValueChanged(app, event)
            value = app.ZoomOnButton.Value;
            if value
                app.ZoomOnButton.Text='Zoom off';
                zoom(app.UIAxes,'on'); 
            else
                app.ZoomOnButton.Text='Zoom on'; 
                zoom(app.UIAxes,'off'); 
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ADCIRCAnimatorUIFigure = uifigure('Visible', 'off');
            app.ADCIRCAnimatorUIFigure.Name = 'ADCIRC Animator';
            app.ADCIRCAnimatorUIFigure.SizeChangedFcn = createCallbackFcn(app, @DrawFrameButtonPushed, true);
            app.ADCIRCAnimatorUIFigure.WindowButtonUpFcn = createCallbackFcn(app, @DrawFrameButtonPushed, true);
            app.Menu = uimenu(app.ADCIRCAnimatorUIFigure);
            app.Menu.Text = 'Menu';
            app.GridLayout = uigridlayout(app.ADCIRCAnimatorUIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'Lon')
            ylabel(app.UIAxes, 'Lat')
            app.LOADButton = uibutton(app.GridLayout, 'push');
            app.LOADButton.ButtonPushedFcn = createCallbackFcn(app, @LOADButtonPushed, true);
            app.LOADButton.Text = 'LOAD';
            app.BrowserButton = uibutton(app.GridLayout, 'push');
            app.BrowserButton.ButtonPushedFcn = createCallbackFcn(app, @BrowserButtonPushed, true);
            app.BrowserButton.Text = {'Browser'; ''};
            app.DrawFrameButton = uibutton(app.GridLayout, 'push');
            app.DrawFrameButton.ButtonPushedFcn = createCallbackFcn(app, @DrawFrameButtonPushed, true);
            app.DrawFrameButton.Text = {'Draw Frame'; ''};
            app.GOButton = uibutton(app.GridLayout, 'state');
            app.GOButton.ValueChangedFcn = createCallbackFcn(app, @GOButtonValueChanged, true);
            app.GOButton.Text = 'GO';
            app.VectorPropertiesPanel = uipanel(app.GridLayout);
            app.VectorPropertiesPanel.Title = 'Vector Properties';
            app.ScaleEditFieldLabel = uilabel(app.VectorPropertiesPanel);
            app.ScaleEditFieldLabel.Text = {'Scale : '; ''};
            app.ScaleEditField = uieditfield(app.VectorPropertiesPanel, 'text');
            app.ScaleEditField.ValueChangedFcn = createCallbackFcn(app, @ScaleEditFieldValueChanged, true);
            app.ScaleEditField.Value = '1';
            app.StrideEditFieldLabel = uilabel(app.VectorPropertiesPanel);
            app.StrideEditFieldLabel.Text = 'Stride : ';
            app.StrideEditField = uieditfield(app.VectorPropertiesPanel, 'text');
            app.StrideEditField.ValueChangedFcn = createCallbackFcn(app, @ScaleEditFieldValueChanged, true);
            app.StrideEditField.Value = '10';
            app.VectorColorLabel = uilabel(app.VectorPropertiesPanel);
            app.VectorColorLabel.Text = 'Vector Color : ';
            app.VectorColorDropDown = uidropdown(app.VectorPropertiesPanel);
            app.VectorColorDropDown.Items = {'Black', 'Red', 'White', 'Blue'};
            app.VectorColorDropDown.ItemsData = {'k', 'r', 'w', 'b'};
            app.VectorColorDropDown.ValueChangedFcn = createCallbackFcn(app, @VectorColorDropDownValueChanged, true);
            app.VectorColorDropDown.Value = 'k';
            app.ScalarPropertiesPanel = uipanel(app.GridLayout);
            app.ScalarPropertiesPanel.Title = 'Scalar Properties';
            app.ColormapDropDownLabel = uilabel(app.ScalarPropertiesPanel);
            app.ColormapDropDownLabel.Text = 'Colormap : ';
            app.ColormapDropDown = uidropdown(app.ScalarPropertiesPanel);
            app.ColormapDropDown.Items = {'Jet', 'NOAA', 'Parula'};
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.ColormapDropDown.Value = 'Jet';
            app.AdvanceButton = uibutton(app.GridLayout, 'push');
            app.AdvanceButton.ButtonPushedFcn = createCallbackFcn(app, @AdvanceButtonPushed, true);
            app.AdvanceButton.Text = {'Advance'; ''};
            app.TimeIndexLabel = uilabel(app.GridLayout);
            app.TimeIndexLabel.Text = 'Time Index : ';
            app.TimeIndexSlider = uislider(app.GridLayout);
            app.TimeIndexSlider.ValueChangedFcn = createCallbackFcn(app, @TimeSliderIndexEditFieldValueChanged, true);
            app.CurrentIndexEditFieldLabel = uilabel(app.GridLayout);
            app.CurrentIndexEditFieldLabel.Text = 'Current Index : ';
            app.CurrentIndexEditField = uieditfield(app.GridLayout, 'numeric');
            app.CurrentIndexEditField.Limits = [1 Inf];
            app.CurrentIndexEditField.ValueDisplayFormat = '%.0f';
            app.CurrentIndexEditField.ValueChangedFcn = createCallbackFcn(app, @TimeSliderIndexEditFieldValueChanged, true);
            app.CurrentIndexEditField.Value = 1;
            app.MessagesLabel = uilabel(app.GridLayout);
            app.MessagesLabel.Text = {'Messages : '; ''};
            app.MessagesTextArea = uitextarea(app.GridLayout);
            app.CurrentDirectoryLabel = uilabel(app.GridLayout);
            app.CurrentDirectoryLabel.Text = 'Current Directory : ';
            app.CurrentDirectoryTextArea = uitextarea(app.GridLayout);
            app.StatusLabel = uilabel(app.GridLayout);
            app.StatusLabel.Text = 'Status : ';
            app.StatusLamp = uilamp(app.GridLayout);
            app.AxisEqualButton = uibutton(app.GridLayout, 'push');
            app.AxisEqualButton.ButtonPushedFcn = createCallbackFcn(app, @AxisEqualButtonPushed, true);
            app.AxisEqualButton.Text = 'Axis Equal';
            app.AxisTightButton = uibutton(app.GridLayout, 'push');
            app.AxisTightButton.ButtonPushedFcn = createCallbackFcn(app, @AxisTightButtonPushed, true);
            app.AxisTightButton.Text = 'Axis Tight';
            app.TimeStrideEditFieldLabel = uilabel(app.GridLayout);
            app.TimeStrideEditFieldLabel.Text = 'Time Stride : ';
            app.TimeStrideEditField = uieditfield(app.GridLayout, 'numeric');
            app.TimeStrideEditField.Limits = [0 Inf];
            app.TimeStrideEditField.ValueDisplayFormat = '%.0f';
            app.TimeStrideEditField.Value = 1;
            app.GridPanel = uipanel(app.GridLayout);
            app.GridPanel.Title = 'Grid';
            app.ElementsSwitchLabel = uilabel(app.GridPanel);
            app.ElementsSwitchLabel.Text = 'Elements';
            app.ElementsSwitch = uiswitch(app.GridPanel, 'toggle');
            app.ElementsSwitch.ValueChangedFcn = createCallbackFcn(app, @ElementsButtonValueChanged2, true);
            app.ZoomOnButton = uibutton(app.GridLayout, 'state');
            app.ZoomOnButton.ValueChangedFcn = createCallbackFcn(app, @ZoomOnButtonValueChanged, true);
            app.ZoomOnButton.Text = 'Zoom On';
            app.PanOnButton = uibutton(app.GridLayout, 'state');
            app.PanOnButton.Text = 'Pan On';
            % Show the figure after all components are created
            app.ADCIRCAnimatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = adcircAnimator(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ADCIRCAnimatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ADCIRCAnimatorUIFigure)
        end
    end
end