classdef adcircAnimator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ADCIRCAnimatorV11UIFigure     matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        ScalarsPanel                  matlab.ui.container.Panel
        GridLayout4                   matlab.ui.container.GridLayout
        ColormapLabel                 matlab.ui.control.Label
        ColormapDropDown              matlab.ui.control.DropDown
        NumColorsLabel                matlab.ui.control.Label
        NumColorsEditField            matlab.ui.control.NumericEditField
        MaxinViewTextAreaLabel        matlab.ui.control.Label
        MaxinViewTextArea             matlab.ui.control.TextArea
        MininViewTextAreaLabel        matlab.ui.control.Label
        MininViewTextArea             matlab.ui.control.TextArea
        ColorMinEditField_2Label      matlab.ui.control.Label
        ColorMinEditField_2           matlab.ui.control.NumericEditField
        ColorMaxEditField_2Label      matlab.ui.control.Label
        ColorMaxEditField_2           matlab.ui.control.NumericEditField
        AutoColorOffButton            matlab.ui.control.StateButton
        SurfaceVisibilitySwitch       matlab.ui.control.ToggleSwitch
        SurfaceDeleteButton           matlab.ui.control.Button
        ScalarsListBoxLabel           matlab.ui.control.Label
        ScalarsListBox                matlab.ui.control.ListBox
        MessagesLabel                 matlab.ui.control.Label
        CurrentSolutionTextAreaLabel  matlab.ui.control.Label
        MessagesTextArea              matlab.ui.control.TextArea
        CurrentSolutionTextArea       matlab.ui.control.TextArea
        GridPanel                     matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        ElementsLabel                 matlab.ui.control.Label
        ElementsSwitch                matlab.ui.control.ToggleSwitch
        ContoursLabel                 matlab.ui.control.Label
        ContoursSwitch                matlab.ui.control.Switch
        NumNodesLabel                 matlab.ui.control.Label
        NumNodesSwitch                matlab.ui.control.ToggleSwitch
        NumElemsLabel                 matlab.ui.control.Label
        NumElemsSwitch                matlab.ui.control.ToggleSwitch
        NumBathyLabel                 matlab.ui.control.Label
        NumBathySwitch                matlab.ui.control.ToggleSwitch
        DepthValuesEditField          matlab.ui.control.EditField
        DepthValuesLabel              matlab.ui.control.Label
        ClearContoursButton           matlab.ui.control.Button
        GotoGlobalMaxButton           matlab.ui.control.Button
        GotoViewMaxButton             matlab.ui.control.Button
        TimeSeriesPanel               matlab.ui.container.Panel
        GridLayout5                   matlab.ui.container.GridLayout
        SampleSolutionCheckBox        matlab.ui.control.CheckBox
        LegendLabel                   matlab.ui.control.Label
        LegendSwitch                  matlab.ui.control.ToggleSwitch
        StatusLabel                   matlab.ui.control.Label
        FrameControlPanel             matlab.ui.container.Panel
        BackwardButton                matlab.ui.control.Button
        ForwardButton                 matlab.ui.control.Button
        GOButton                      matlab.ui.control.StateButton
        TimeStrideLabel               matlab.ui.control.Label
        TimeStrideEditField           matlab.ui.control.NumericEditField
        CurrentIndexLabel             matlab.ui.control.Label
        CurrentIndexEditField         matlab.ui.control.NumericEditField
        TImeIndexPanel                matlab.ui.container.Panel
        SliderLabel                   matlab.ui.control.Label
        Slider                        matlab.ui.control.Slider
        InfoPanel                     matlab.ui.container.Panel
        GridNameTextAreaLabel         matlab.ui.control.Label
        GridNameTextArea              matlab.ui.control.TextArea
        ElementsTextAreaLabel         matlab.ui.control.Label
        ElementsTextArea              matlab.ui.control.TextArea
        NodesTextAreaLabel            matlab.ui.control.Label
        NodesTextArea                 matlab.ui.control.TextArea
        OutputTimeStepEditFieldLabel  matlab.ui.control.Label
        OutputTimeStepEditField       matlab.ui.control.EditField
        RNDAYTextAreaLabel            matlab.ui.control.Label
        RNDAYTextArea                 matlab.ui.control.TextArea
        StatusLamp                    matlab.ui.control.Lamp
        StuffPanel                    matlab.ui.container.Panel
        GridLayout6                   matlab.ui.container.GridLayout
        LOADButton                    matlab.ui.control.Button
        AxisEqualButton               matlab.ui.control.Button
        AxisTightButton               matlab.ui.control.Button
        DrawFrameButton               matlab.ui.control.Button
        GMapButton                    matlab.ui.control.Button
        BrowserButton                 matlab.ui.control.Button
        testVectorsPanel              matlab.ui.container.Panel
        AxesLimitsPanel               matlab.ui.container.Panel
        AxesLimitsEditFieldLabel      matlab.ui.control.Label
        AxesLimitsEditField           matlab.ui.control.EditField
        VectorsTabGroup               matlab.ui.container.TabGroup
        VectorsTab                    matlab.ui.container.Tab
        VectorsPanel_1                matlab.ui.container.Panel
        GridLayout3_2                 matlab.ui.container.GridLayout
        ScaleEditFieldLabel_2         matlab.ui.control.Label
        ScaleEditField_2              matlab.ui.control.EditField
        StrideEditFieldLabel_2        matlab.ui.control.Label
        StrideEditField_2             matlab.ui.control.EditField
        VectorColorLabel_2            matlab.ui.control.Label
        VectorColorDropDown_2         matlab.ui.control.DropDown
        MaxinViewTextArea_2Label_2    matlab.ui.control.Label
        MaxinViewTextArea_3           matlab.ui.control.TextArea
        VectorVisibilitySwitch_2      matlab.ui.control.ToggleSwitch
        VectorDeleteButton_2          matlab.ui.control.Button
        VectorsDropDown_2Label        matlab.ui.control.Label
        VectorsDropDown_2             matlab.ui.control.DropDown
        GriddedTab                    matlab.ui.container.Tab
        Panel_2                       matlab.ui.container.Panel
        GridVectorsCheckBox           matlab.ui.control.CheckBox
        ResolutiondegEditFieldLabel   matlab.ui.control.Label
        ResolutiondegEditField        matlab.ui.control.NumericEditField
        ComputeButton                 matlab.ui.control.StateButton
        VectorsPanel                  matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        ScaleEditFieldLabel           matlab.ui.control.Label
        ScaleEditField                matlab.ui.control.EditField
        StrideEditFieldLabel          matlab.ui.control.Label
        StrideEditField               matlab.ui.control.EditField
        VectorColorLabel              matlab.ui.control.Label
        VectorColorDropDown           matlab.ui.control.DropDown
        MaxinViewTextArea_2Label      matlab.ui.control.Label
        MaxinViewTextArea_2           matlab.ui.control.TextArea
        VectorVisibilitySwitch        matlab.ui.control.ToggleSwitch
        VectorDeleteButton            matlab.ui.control.Button
        VectorsLabel                  matlab.ui.control.Label
        VectorsDropDown               matlab.ui.control.DropDown
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Grid  % Description
        Url = './'
        ColorMin = []
        ColorMax = []
        AxisLims=[];
        ColorPriorState = [NaN NaN]
        ContourVals = [0 10  25  50  100]
        handles = {}
        
        nTimes = []
        StartingTime = datetime(0,0,0)
        IterStart  = 1
        IterStride = 1
        IterStop   = Inf
        time = []
        autoLoad = true
        colormesh2d_handle = []
        boundary_handle = []
        elements_handle = []
        vecplot_handle = []
        colormap = jet(24)
        vector_properties = {}
        %handle_zoom = []
        contours_handle = []
        numelemtext_handle = []
        numnodestext_handle = []
        numbathytext_handle = []
        gmap_handle = []
        time_series_figs = {}
        time_series_axes_scalar = []
        time_series_axes_vector = []
        
        nc_scalar = []
        nc_vector = []
        
        Datasets = struct('Scalars',[],'Vectors', [])
        
        FontSizeBase = 20
        
    end
    
    properties (Access=private)
        ncfiles={'fort.63.nc' 
                 'fort.73.nc' 
                 'fort.75.nc'
                 'swan_HS.63.nc' 
                 'dusdt.63.nc'
                 'usdalphadt.63.nc'
                 'centaccel.63.nc'
                 'bernaccel.63.nc'
                 'us.63.nc'
                 'Rs.63.nc'
                 'alpha.63.nc'
                 'corraccel.63.nc'
                 'botfric.63.nc'
                 'gdzetads.63.nc'
                 'gdzetadn.63.nc'
                 'swindstress.63.nc'
                 'nwindstress.63.nc'
                 'satmospres.63.nc'
                 'natmospres.63.nc'
                 'maxele.63.nc'};
        
        ncvarnames={'zeta'
                    'pressure' 
                    'windspd'
                    'swan_HS' 
                    'dusdt'
                    'usdalphadt'
                    'centaccel'
                    'bernaccel'
                    'us'
                    'Rs'
                    'alpha'
                    'corraccel'
                    'botfric'
                    'gdzetads'
                    'gdzetadn'
                    'swindstress'
                    'nwindstress'
                    'satmospres'
                    'natmospres'
                    'zeta_max'};
        
        vncfiles={'fort.64.nc'
                  'fort.74.nc'
                  };
        vncvarnames={
            {'u-vel','v-vel'}
            {'windx','windy'}
            };
        
        ColOrd = jet(8);
    end
    
    methods (Access = private)
        
        function results = GetNCs(app)
            
            app.MessagesTextArea.Value = ['Opening nc files @ ' app.Url ];
            
            iscalar=0;
            ivector=0;
            Scalars=cell({});
            Vectors=cell({});
            scalar_names={};
            vector_names={};
            
            for i =1:length(app.ncfiles)
                
                try
                    temp = ncgeodataset([app.Url '/' app.ncfiles{i}]);
                    iscalar=iscalar+1;
                    Scalars{iscalar}=app.AddDataset(temp, app.ncvarnames{i});
                    scalar_names{iscalar}=app.ncvarnames{i};
                    
                catch ME
                    
                    app.MessagesTextArea.Value = [app.ncfiles{i} ' not Found. Setting to None.'] ;
                    
                end                
            end
            
            for i =1:length(app.vncfiles)
                
                try
                    temp = ncgeodataset([app.Url '/' app.vncfiles{i}]);
                    ivector=ivector+1;
                    Vectors{ivector}=app.AddDataset(temp, app.vncvarnames{i});
                    vector_names{ivector}=app.vncvarnames{i};
                    
                catch ME
                    
                    app.MessagesTextArea.Value = [app.vncfiles{i} ' not Found. Setting to None.'] ;
                    
                end                
            end
            
            app.Datasets.vector_names=vector_names;
            if ~isempty(vector_names)
                app.Datasets.vector_names=vector_names;
                app.Datasets.Vectors=Vectors;
                app.nc_vector=app.Datasets.Vectors{1}.dataset;
            end
            
            app.Datasets.Scalars=Scalars;
            app.Datasets.scalar_names=scalar_names;            
            app.nc_scalar=app.Datasets.Scalars{1}.dataset;
            setvariablemenuoptions(app);
            
        end
                
        function ds = AddDataset(app,dataset,name)
            ds.dataset=dataset;
            ds.name=name;
        end
        
        function results = GetTimes(app)
            
            app.MessagesTextArea.Value = 'Getting/setting times ...';
            
            time = app.Datasets.Scalars{1}.dataset.time('time');
            
            if isempty(time)
                error('time in fort.63.nc is length 0.  This is pointless.')
            end
            
            time = datetime(datevec(time));
            app.nTimes = size(time,1);
            t = time;
            
            if app.StartingTime ~= datetime(0,0,0)
                t = t - t(1);
                t = t + app.StartingTime;
            end
            app.time=t;
            
            % set the slider min/max indices
            app.Slider.Limits = [1 app.nTimes];
            app.IterStop = app.nTimes;
            app.CurrentIndexEditField.Value = 1;
            app.Slider.Value = 1;
            dt=time(2)-time(1);
            app.OutputTimeStepEditField.Value=sprintf('%d sec',seconds(dt));
            app.RNDAYTextArea.Value=char(time(1));
            app.SetTitle()
            
        end
        
        function results = InitFrame(app)
            
            app.MessagesTextArea.Value = 'Initializing main axes ...';
            
            %app.fign=figure('Position',app.FigurePosition);
            axis(app.UIAxes,'equal')
            %axis(app.UIAxes,'tight')
            SetColorLimits(app)
            SetColormap(app)
            SetAxisLims(app)
            
        end
        
        function SetColormap(app)
            
            colormap(app.UIAxes,app.colormap);
            colorbar(app.UIAxes)
            
        end
        
        function results = SetColorLimits(app)
            
            cmax = app.ColorMaxEditField_2.Value;
            cmin = app.ColorMinEditField_2.Value;
            set(app.UIAxes,'CLim',[cmin cmax])
            app.ColorPriorState=caxis(app.UIAxes);
            %
            %                  FieldMax=ceil(maxThisData/ColorIncrement)*ColorIncrement;
            %      FieldMin=floor(minThisData/ColorIncrement)*ColorIncrement;
            %
            %      set(Handles.CMax,'String',sprintf('%.2f',FieldMax))
            %      set(Handles.CMin,'String',sprintf('%.2f',FieldMin))
            %      set(Handles.NCol,'String',sprintf('%d',NumberOfColors))
            %      PossibleMaps=cellstr(get(Handles.ColormapSetter,'String'));
            %      CurrentValue=get(Handles.ColormapSetter,'Value');
            %      CurrentMap=PossibleMaps{CurrentValue};
            %      cmap=eval(sprintf('%s(%d)',CurrentMap,NumberOfColors));
            %      CLim([FieldMin FieldMax])
            %      colormap(cmap)
            
        end
        
        function results = GetGrid(app)
            
            app.MessagesTextArea.Value = 'Getting grid ...';
            app.Grid = ExtractGrid(app.Datasets.Scalars{1}.dataset);
            app.GridNameTextArea.Value=app.Grid.name;
            app.NodesTextArea.Value=sprintf('%d',app.Grid.nn);
            app.ElementsTextArea.Value=sprintf('%d',app.Grid.ne);
            
        end
        
        function PlotBnd(app)
            
            app.MessagesTextArea.Value = 'Drawing boundary ...';
            app.boundary_handle = plotbnd(app.Grid,'Axes',app.UIAxes,'LineWidth',.2,'Color','m','Clipping','on');
            
        end
        
        function results = DrawContours(app)
            
            app.MessagesTextArea.Value = 'Drawing contours ...';
            temp=app.DepthValuesEditField.Value;
            temp=split(temp);
            for i=1:length(temp)
                if ~isempty(temp{i})
                    cvals(i)=str2double(temp{i});
                end
            end
            app.contours_handle = lcontour(app.Grid,'z',cvals,'Axes',app.UIAxes,'Color','k','LineWidth',1);
            app.ContoursSwitch.Value = 'On';
            app.MessagesTextArea.Value = 'Done.';
            
        end
        
        function results = DrawFrame(app)
            
            SetStatusBusy(app)

            idx = app.CurrentIndexEditField.Value;
            app.MessagesTextArea.Value = string(idx);
            % check show/hide status
            if strcmp(app.SurfaceVisibilitySwitch.Value,'Show')
                app.colormesh2d_handle = DrawSurf(app);
            end
            if strcmp(app.VectorsPanel.Enable,'on')
                if strcmp(app.VectorVisibilitySwitch.Value,'Show') 
                    app.vecplot_handle = DrawVec(app);
                end
            end
            
            % check to see what things have been toggled.
            app.NumElemsSwitchValueChanged();
            app.NumNodesSwitchValueChanged();
            app.NumBathySwitchValueChanged();
            
            SetStatusReady(app)
           
        end
        
        function h = DrawSurf(app)
            
            app.MessagesTextArea.Value = 'Drawing surface ...';
            drawnow
            
            if ishandle(app.colormesh2d_handle)
                delete(app.colormesh2d_handle)
            end
            
            nc=app.nc_scalar;
            varname=app.ScalarsListBox.Value;
            %             idx=find(strcmp(app.ScalarsListBoxDropDown.Value,app.Datasets.scalar_names));
            %             nc=app.Datasets.Scalars{idx}.dataset;
            
            if length(nc.axes{varname})==2
                s = nc{varname}(:);
            else
                s = nc{varname}(app.CurrentIndexEditField.Value,:);
            end
            h = colormesh2d(app.Grid,s,'Axes',app.UIAxes);
            %h = colormesh2d(app.Grid,app.nc64{'v-vel'}(app.CurrentIndexEditField.Value,:),'Axes',app.UIAxes);
            h.Clipping = 'on';
            % get the viewable region from the current axes
            nodes_in_reg = GetNodesInView(app);
            maxreg = max(s(nodes_in_reg));
            app.MaxinViewTextArea.Value = sprintf('%.3f m',maxreg);
            minreg = min(s(nodes_in_reg));
            app.MininViewTextArea.Value = sprintf('%.3f m',minreg);
            app.MessagesTextArea.Value = 'Done.';
            
        end
        
        function h = DrawVec(app)
            
            app.MessagesTextArea.Value = 'Drawing vectors  ...';
            drawnow
            
            if ishandle(app.vecplot_handle)
                delete(app.vecplot_handle)
            end
            
            varnames=split(app.VectorsDropDown.Value);
            nc=app.nc_vector;
            %nc=app.nc64;
            u = nc{varnames{1}}(app.CurrentIndexEditField.Value,:);
            u = u(:);
            v = nc{varnames{2}}(app.CurrentIndexEditField.Value,:);
            v = v(:);
            vsca = str2double(app.ScaleEditField.Value);
            vstr = str2double(app.StrideEditField.Value);
            
            vcol = app.VectorColorDropDown.Value;
            ax=axis(app.UIAxes);
            h = vecplot(app.Grid.x,app.Grid.y,u,v,'Axes',app.UIAxes,...
                'ScaleLabel','no scale',...
                'ScaleFac',vsca,'Stride',vstr,'Color',vcol,...
                'Clipping','on');
            axis(app.UIAxes,ax)
            
            %h.ZData=10*ones(size(h.XData));
            %if ~isempty(h)
            %    h.Clipping = 'on';
            %end
            
            spd = sqrt(u.^2 + v.^2);
            % get the viewable region from the current axes
            nodes_in_reg = GetNodesInView(app);
            maxreg = max(spd(nodes_in_reg));
            app.MaxinViewTextArea_2.Value = sprintf('%.2f m/s',maxreg);
            
            app.MessagesTextArea.Value = 'Ready.';

        end
        
        function results = SetStatusReady(app)
            
            app.StatusLamp.Color = 'g';
            app.MessagesTextArea.Value = 'Ready.';
            
        end
        
        function results = SetStatusBusy(app)
            
            app.StatusLamp.Color = 'r';
            pause(.001)
            
        end
        
        function results = Animate(app)
            
            app.IterStart = app.CurrentIndexEditField.Value;
            app.IterStride = app.TimeStrideEditField.Value;
            app.MessagesTextArea.Value = 'Animating ...';

            SetStatusBusy(app)

            for it =  app.IterStart:app.IterStride:app.IterStop
                app.DrawFrame()
                app.CurrentIndexEditField.Value = it;
                app.Slider.Value = it;
                app.RNDAYTextArea.Value=char(app.time(it));
                % set title
                app.SetTitle()
                drawnow
                % check value of "GO" button
                if ~app.GOButton.Value
                    app.MessagesTextArea.Value = 'Animation paused ...';
                    break
                end
            end
            
            SetStatusReady(app)
            
        end
        
        function results = DrawElems(app)
            
            SetStatusBusy(app)
            app.MessagesTextArea.Value = 'Drawing Elements ...';
            
            app.elements_handle = drawelems(app.Grid,'Axes',app.UIAxes);
            app.elements_handle.LineWidth = .25;
            app.elements_handle.Color = [1 1 1]*.6;
            
            SetStatusReady(app)
            
        end
        
        %         function results = NOAA(app)
        %             ncol = app.NumColorsEditField.value;
        %             results = noaa_cmap(ncol);
        %         end
        
        function nodes_in_reg = GetNodesInView(app)
            % get the viewable region from the current axes
            XLim = get(app.UIAxes,'XLim');
            YLim = get(app.UIAxes,'YLim');
            %Px = [XLim(1) XLim(2) XLim(2) XLim(1) XLim(1)];
            %Py = [YLim(1) YLim(1) YLim(2) YLim(2) YLim(1)];
            %nodes_in_reg = inpolygon(app.Grid.x,app.Grid.y,Px,Py);
            nodes_in_reg = app.Grid.x > XLim(1) & app.Grid.x < XLim(2) & ...
                           app.Grid.y > YLim(1) & app.Grid.y < YLim(2);
        end
        
        function results = SampleSolution(app,event)
            
            % determine if point is in axes
            if ~isempty(event.Source.CurrentObject) && isequal(event.Source.CurrentObject.Tag,'colorsurf')
                % get point
                cp = app.UIAxes.CurrentPoint;
                % find nearest node
                j = findelem(app.Grid,cp(1),cp(3));
                if isnan(j)
                    app.MessagesTextArea.Value = 'Point not in grid.  Try again...';
                    return
                end
                i = app.Grid.e(j,1);
                if isempty(app.time_series_figs) || ~ishandle(app.time_series_figs)
                    app.time_series_figs = figure;
                    p = app.time_series_figs.Position;
                    app.time_series_figs.Position = [p(1) p(2) 600 300];
                    app.time_series_figs.Name = 'Time Series';
                    app.time_series_axes_scalar=subplot(311);
                    app.time_series_axes_vector(1)=subplot(312);
                    app.time_series_axes_vector(2)=subplot(313);
                end
                
                varname=app.ScalarsListBox.Value;
                %idx=find(strcmp(app.ScalarsListBoxDropDown.Value,app.Datasets.scalar_names));
                %nc=app.Datasets.Scalars{idx}.dataset;
                
                figure(app.time_series_figs)
                
                ncs=app.nc_scalar;
                ncv=app.nc_vector;
                
                axes(app.time_series_axes_scalar)
                z = ncs{varname}(:,i);
                %ColOrd = get(app.UIAxes,'ColorOrder');
                ic = rem(length(app.time_series_axes_scalar.Children)+1,length(app.ColOrd))+1;
                line(app.time,z,'DisplayName',sprintf('%s @ %d',varname,i),'Color',app.ColOrd(ic,:))
                legend('location','northwest')
                tsaxes.XMinorGrid = 'on';
                tsaxes.YMinorGrid = 'on';
                
                if ~isempty(ncv)
                    varnames=split(app.VectorsDropDown.Value);
                    u = ncv{varnames{1}}(:,i);
                    v = ncv{varnames{2}}(:,i);
                    axes(app.time_series_axes_vector(1))
                    line(app.time, u, 'DisplayName',sprintf('%s @ %d',varnames{1},i), 'Color',app.ColOrd(ic,:))
                    legend(app.time_series_axes_vector(1),'location','northwest')
                    axes(app.time_series_axes_vector(2))
                    line(app.time, v, 'DisplayName',sprintf('%s @ %d',varnames{2},i), 'Color',app.ColOrd(ic,:), 'linestyle', '--')
                    legend(app.time_series_axes_vector(2),'location','northwest')
                    tsaxes.XMinorGrid = 'on';
                    tsaxes.YMinorGrid = 'on';
                end
                
            end
        end
        
        function results = SetTitle(app)
            
            str=app.RNDAYTextArea.Value;
            title(app.UIAxes, str, 'FontSize',30)
            
        end
        
        function results = setvariablemenuoptions(app)
            
            app.ScalarsListBox.Items=app.Datasets.scalar_names;
            app.ScalarsListBox.Value=app.Datasets.scalar_names{1};
            
            temp={};
            if ~isempty(app.Datasets.vector_names)
                for i=1:length(app.Datasets.vector_names)
                    temp{i}=join(app.Datasets.vector_names{i});
                end
                app.VectorsDropDown.Items=[temp{:}];
                app.VectorsDropDown.Value=temp{1};
            else
                app.VectorsPanel.Enable='off';
%                 for i = 1:length(app.VectorsPanel.Children.Children)
%                     app.VectorsPanel.Children.Children(i).Enable='off';
%                 end
            end
        end

        function results = GetMinMaxInView(app)
            
            nodes_in_reg = GetNodesInView(app);
            z=app.nc_scalar;
            
        end
        
        function results = SetMinMaxValues(app)
            
            disp('SetMinMaxValues: Not implemented yet.')

        end
        
        function customcallback(src,event)
            switch src.Value
                case 'off'
                    event.Axes.XGrid = 'off';
                    event.Axes.YGrid = 'off';
                    event.Axes.ZGrid = 'off';
                case 'on'
                    event.Axes.XGrid = 'on';
                    event.Axes.YGrid = 'on';
                    event.Axes.ZGrid = 'on';
            end
        end
        
        function mypostcallback(app,event)
            disp('A zoom has occured.');
        end
        
        function gridlinecallback(app, event)
             disp('gridlinecallback: Not implemented yet.')
%              app.UIAxes.XGrid = 'on';
%              app.UIAxes.XMinorGrid = 'on';
%              app.UIAxes.YGrid = 'on';
%              app.UIAxes.YMinorGrid = 'on';
        end
        
        function results = SetAxisLims(app)
            
            if ~isempty(app.AxisLims)
               axis(app.UIAxes,app.AxisLims)
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            
            app.ADCIRCAnimatorV11UIFigure.CreateFcn='';
            
            % check for nctoolbox
            if ~exist('ncgeodataset','file')
                error('ncgeodataset not found. This is terminal.')
            end
            
            %process varargin
            app.FontSizeBase=20;
            WindowName=[];
            k=1;
            while k<length(varargin)
              switch lower(varargin{k})
                case 'axislims'
                  app.AxisLims=varargin{k+1};
                  varargin([k k+1])=[];
               case 'url'
                  app.Url=varargin{k+1};
                  varargin([k k+1])=[];
               case 'windowname'
                  WindowName=varargin{k+1};
                  varargin([k k+1])=[];
                  case 'fontsizebase'
                  app.FontSizeBase=varargin{k+1};
                  varargin([k k+1])=[];
               otherwise
                  k=k+2;
              end
            end            
            
            if strmatch(app.Url,'./')
                pathname=pwd;
                if ~exist('fort.63.nc','file') && ~exist('fort.64.nc','file')
                    msgstr = [ 'fort.63.nc and fort.64.nc not found in ' pwd ...
                        '. Navigate to a ADCIRC netCDF file ... '];
                    
                    app.MessagesTextArea.Value = msgstr;
                    
                    [filename, pathname] = uigetfile({'*.nc'}, ...
                        'Navigate to a ADCIRC netCDF file ... ');
                    if ~any(strcmp(filename,app.ncfiles))
                        error([filename ' not found in allowable filelist.'])
                    end
                end
                app.Url = pathname;
            end
            
%           https://www.mathworks.com/matlabcentral/answers/...
%           653383-app-designer-matlab-2020b-does-not-open-mlapp-file?s_tid=ans_lp_feed_leaf
            enableLegacyExplorationModes(app.ADCIRCAnimatorV11UIFigure);
            
            [tb,btns] = axtoolbar(app.UIAxes,...
                 {'export','datacursor','zoomin','zoomout','restoreview','pan'});
            zoomObj = zoom(app.ADCIRCAnimatorV11UIFigure);
            zoomObj.ActionPostCallback = @mypostcallback;
            %'disp(''here'')';
%             a1=app.UIAxes.Toolbar.Children(3).ValueChangedFcn;
%             a2='disp(''hereXYZ'')';
%             app.UIAxes.Toolbar.Children(3).ValueChangedFcn={a1,a2};

%             btn = axtoolbarbtn(tb,'state');
%             btn.Icon = 'hashicon.png';
%             btn.Tooltip = 'Grid Lines';
%             btn.ValueChangedFcn = @customcallback;

              if ~isempty(WindowName)
                  app.ADCIRCAnimatorV11UIFigure.Name=WindowName;
              end
            
            if app.autoLoad
                LOADButtonPushed(app)
            end
            
            %app.ADCIRCAnimatorV11UIFigure.Position(3)
%             h = findobj(app.ADCIRCAnimatorV11UIFigure, '-property', 'FontSize');
%             hFontSize = cell2mat(get(h,'FontSize'));
%             position = app.ADCIRCAnimatorV11UIFigure.Position;
%                         
%             widthF = position(3);
%                         
%             newFontSize = double(widthF) * hFontSize / 1463.0;
%             set(h,{'FontSize'}, num2cell(newFontSize));
            
        end

        % Button pushed function: LOADButton
        function LOADButtonPushed(app, event)
            
            SetStatusBusy(app)
            app.CurrentSolutionTextArea.Value = app.Url;
            app.MessagesTextArea.Value = sprintf('Loading ADCIRC files in %s ...',pwd);
            app.GetNCs()
            
            cla(app.UIAxes)
            app.GetGrid()
            app.InitFrame()
            app.PlotBnd()
            app.DrawContours()
            app.GetTimes()
            app.DrawFrame()
            %app.CurrentSolutionTextArea.Value = pwd;
            value=axis(app.UIAxes);
            app.AxesLimitsEditField.Value=regexprep(num2str(value), '\s+', ' ');
            
            % enable functions
            app.GOButton.Enable = 'on';
            app.ForwardButton.Enable = 'on';
            app.BackwardButton.Enable = 'on';
            SetStatusReady(app)
            app.MessagesTextArea.Value = sprintf('Ready...');
            
        end

        % Button pushed function: BrowserButton
        function BrowserButtonPushed(app, event)
            
            selpath = uigetdir;
            if exist(selpath,'dir')
                cd(selpath)
            end
            app.MessagesTextArea.Value = pwd;
            % need to reset app and reload
            app.LOADButtonPushed()
            
        end

        % Value changed function: ElementsSwitch
        function ElementsButtonValueChanged2(app, event)
            
            SetStatusBusy(app)
            h = findobj(app.UIAxes,'Tag','elements');
            if ishandle(h)
                delete(h)
            else
                app.MessagesTextArea.Value = 'Drawing Elements.';
                app.DrawElems()
            end
            SetStatusReady(app)
            
        end

        % Button pushed function: DrawFrameButton
        function DrawFrameButtonPushed(app, event)
            
            %disp('inside DrawFrameButtonPushed')
            SetStatusBusy(app)
            app.DrawFrame()
            SetStatusReady(app)
            
        end

        % Value changed function: Slider
        function TimeSliderIndexEditFieldValueChanged(app, event)
            
            value = app.Slider.Value;
            app.CurrentIndexEditField.Value=floor(value);
            SetStatusBusy(app)
            app.DrawFrame()
            app.RNDAYTextArea.Value=char(app.time(floor(value)));
            app.SetTitle()
            SetStatusReady(app)
            
        end

        % Value changed function: GOButton
        function GOButtonValueChanged(app, event)
            
            value = app.GOButton.Value;
            
            % if 1, start animation, else stop
            if value
                app.GOButton.BackgroundColor='r';
                app.GOButton.Text='STOP';
                
                app.IterStart=app.CurrentIndexEditField.Value;
                app.IterStride=app.TimeStrideEditField.Value;
                
                app.Animate()
            else
                app.GOButton.BackgroundColor='g';
                app.GOButton.Text='GO';
            end
            
        end

        % Value changed function: VectorColorDropDown, 
        % VectorColorDropDown_2
        function VectorColorDropDownValueChanged(app, event)
            
            value = app.VectorColorDropDown.Value;
            set(app.vecplot_handle,'Color',value)
            
        end

        % Button pushed function: AxisEqualButton
        function AxisEqualButtonPushed(app, event)
            
            axis(app.UIAxes,'equal')
            
        end

        % Button pushed function: AxisTightButton
        function AxisTightButtonPushed(app, event)
            
            axis(app.UIAxes,'tight')
            
        end

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
            
            % increment frame
            value = app.CurrentIndexEditField.Value;
            stride=app.TimeStrideEditField.Value;
            val=value+stride;
            if val > app.nTimes
                val=app.nTimes;
                app.MessagesTextArea.Value = 'End of series reached.';
            end
            
            app.CurrentIndexEditField.Value=val;
            app.DrawFrame()
            app.Slider.Value=val;
            app.RNDAYTextArea.Value = char(app.time(val));
            app.SetTitle()
            
        end

        % Value changed function: ColormapDropDown, 
        % NumColorsEditField
        function ColormapDropDownValueChanged(app, event)
            
            cmapname = app.ColormapDropDown.Value;
            ncol = app.NumColorsEditField.Value;
            if strcmp(cmapname(end-2:end),'cpt') || strcmp(cmapname(end-2:end),'act')
                cmap=cpt2mat(cmapname,ncol);
            else
                cmap=eval(sprintf('%s(%d)',cmapname,ncol));
            end
            app.colormap=cmap;
            SetColormap(app)
            
        end

        % Value changed function: ScaleEditField, ScaleEditField_2, 
        % StrideEditField, StrideEditField_2
        function ScaleEditFieldValueChanged(app, event)
            
            % value = app.ScaleEditField.Value;
            % app.VectorsPanel
            
            app.vecplot_handle = DrawVec(app);
            
        end

        % Callback function
        function ZoomOnButtonValueChanged(app, event)
            
            value = app.ZoomOnButton.Value;
            
            if value
                app.ZoomOnButton.Text='Zoom off';
                zoom(app.UIAxes,'on');
            else
                app.ZoomOnButton.Text='Zoom on';
                zoom(app.UIAxes,'off');
            end
            
        end

        % Value changed function: CurrentIndexEditField
        function CurrentIndexEditFieldValueChanged(app, event)

            value = app.CurrentIndexEditField.Value;
            app.Slider.Value=floor(value);
            SetStatusBusy(app)
            app.DrawFrame()
            app.RNDAYTextArea.Value = char(app.time(value));
            app.SetTitle()
            SetStatusReady(app)
            
        end

        % Button pushed function: BackwardButton
        function BackwardButtonPushed(app, event)
            
            % decrement frame
            value = app.CurrentIndexEditField.Value;
            stride = app.TimeStrideEditField.Value;
            
            val=value-stride;
            if val < 1
                val=1;
                app.MessagesTextArea.Value = 'Start of series reached.';
            end
            
            app.CurrentIndexEditField.Value = val;
            app.DrawFrame()
            app.Slider.Value = val;
            app.RNDAYTextArea.Value = char(app.time(val));
            app.SetTitle()
            
        end

        % Value changed function: ContoursSwitch
        function ContoursSwitchValueChanged(app, event)
            
            cont_objs=findobj(app.UIAxes,'Tag','contour');
            if strcmp(app.ContoursSwitch.Value,'On')
                set(cont_objs,'Visible','on')
            else
                set(cont_objs,'Visible','off')
            end
            
        end

        % Value changed function: ColorMaxEditField_2, 
        % ColorMinEditField_2
        function ColorMaxEditFieldValueChanged(app, event)
            
            SetColorLimits(app)
            
        end

        % Value changed function: DepthValuesEditField
        function DepthValuesEditFieldValueChanged(app, event)
            
            value = app.DepthValuesEditField.Value;
            app.DrawContours()
            
        end

        % Value changed function: NumElemsSwitch
        function NumElemsSwitchValueChanged(app, event)
            
            % app.MessagesTextArea.Value = 'Drawing Element numbers.';
            SetStatusBusy(app)
            value = app.NumElemsSwitch.Value;
            if  strcmp(value,'Off')
                if ishandle(app.numelemtext_handle)
                    delete(app.numelemtext_handle)
                end
            else
                app.numelemtext_handle=numelems(app.Grid,'Axes',app.UIAxes);
            end
            SetStatusReady(app)
            
        end

        % Value changed function: NumNodesSwitch
        function NumNodesSwitchValueChanged(app, event)
            
            % app.MessagesTextArea.Value = 'Drawing Node numbers.';
            SetStatusBusy(app)
            value = app.NumNodesSwitch.Value;
            if strcmp(value,'Off')
                if ishandle(app.numnodestext_handle)
                    delete(app.numnodestext_handle)
                end
            else
                app.numnodestext_handle = numnodes(app.Grid,'Axes',app.UIAxes,...
                    'HorizontalAlignment','left');
            end
            SetStatusReady(app)
            
        end

        % Value changed function: NumBathySwitch
        function NumBathySwitchValueChanged(app, event)
            
            SetStatusBusy(app)
            value = app.NumBathySwitch.Value;
            if strcmp(value,'Off')
                if ishandle(app.numbathytext_handle)
                    delete(app.numbathytext_handle)
                end
            else
                app.numbathytext_handle = numscal(app.Grid.x,app.Grid.y,app.Grid.z,'Axes',app.UIAxes,...
                    'HorizontalAlignment','right','FontWeight','bold',...
                    'FontAngle','italic');
            end
            SetStatusReady(app)
            
        end

        % Value changed function: AutoColorOffButton
        function AutoColorOffButtonValueChanged(app, event)
            
            value = app.AutoColorOffButton.Value;
            
            if ~value
                app.AutoColorOffButton.Text = 'Auto Color On';
                app.ColorPriorState = caxis(app.UIAxes);
                app.UIAxes.CLimMode = 'auto';
                temp = caxis(app.UIAxes);
                app.ColorMinEditField_2.Value = temp(1);
                app.ColorMaxEditField_2.Value = temp(2);
            else
                app.AutoColorOffButton.Text = 'Auto Color Off';
                caxis(app.UIAxes,app.ColorPriorState)
                temp = caxis(app.UIAxes);
                app.ColorMinEditField_2.Value = temp(1);
                app.ColorMaxEditField_2.Value = temp(2);
                
            end
            
        end

        % Button pushed function: ClearContoursButton
        function ClearContoursButtonPushed(app, event)
            
            app.contours_handle=findobj(app.UIAxes,'Tag','contour');
            delete(app.contours_handle)
            
        end

        % Button pushed function: GMapButton
        function GMapButtonValueChanged(app, event)
            
            value = app.GMapButton.Value;
            if isempty(app.gmap_handle)
                if value
                    app.gmap_handle=plot_google_map('axis',app.UIAxes,'MapType','hybrid');
                else
                    set(app.gmap_handle,'Visible','off')
                    %app.gmap_handle.Visible='off';
                end
            else
                if value
                    set(app.gmap_handle,'Visible','on')
                else
                    set(app.gmap_handle,'Visible','off')
                end
            end
            
        end

        % Button pushed function: GotoGlobalMaxButton
        function GotoGlobalMaxButtonPushed(app, event)
            
            app.MessagesTextArea.Value = 'Going to global max water level.';
            SetStatusBusy(app)
            
            % find max val at this time level.
            z=app.colormesh2d_handle.CData;
            if range(z(:))<1e-4
                app.MessagesTextArea.Value = 'Range too small to display.  Advance the time to get to more action in the solution.';
            else
                [~,b]=max(max(z));
                ax=app.Grid.x(app.Grid.e(b,1));
                ay=app.Grid.y(app.Grid.e(b,1));
                axis(app.UIAxes,[ax+[-.01 .01] ay+[-.01 .01]])
            end
            SetStatusReady(app)
            
        end

        % Value changed function: SampleSolutionCheckBox
        function SampleSolutionCheckBoxValueChanged(app, event)
            
            value = app.SampleSolutionCheckBox.Value;
            
            if value
                %                 % set buttondown
                %                 temp=get(app.ADCIRCAnimatorV11UIFigure,'WindowButtonUpFcn');
                app.MessagesTextArea.Value = 'Click on solution (colorsurf object) in main axes';
                %                 %set(app.ADCIRCAnimatorV11UIFigure,'WindowButtonUpFcn',@SampleSolution(app)));
                %                 app.ADCIRCAnimatorV11UIFigure.WindowButtonUpFcn = [];
                %                 % createCallbackFcn(app, @SampleSolution, true);
                %
                %                 % [X,Y] = ginput(1)
                %                 %waitforbuttonpress;
                %                 %seltype=get(app.ADCIRCAnimatorV11UIFigure,'SelectionType');
                %                 %if ~strcmp(seltype,'normal')break,end
            else
                app.MessagesTextArea.Value = 'Resume normal button functions';
            end
            
        end

        % Window button up function: ADCIRCAnimatorV11UIFigure
        function ADCIRCAnimatorV11UIFigureWindowButtonUp(app, event)
            
            % Determine what conditions exist to enable the right response
            %event.Source.CurrentObject
            if app.SampleSolutionCheckBox.Value
                % WindowButtonUp with "SampleSolution" set.
                % enable time series plots
                app.SampleSolution(event)
%             if isfield(event.Source.CurrentObject,'Type')
%             elseif strcmp(event.Source.CurrentObject.Type,'axes')
%                 %app.DrawFrame()
%                  if ishandle(app.vecplot_handle) && strcmp(app.VectorVisibilitySwitch.Value,'Show')    
%                     delete(app.vecplot_handle)
%                     app.vecplot_handle = DrawVec(app);
%                 end
            end
            
        end

        % Value changed function: LegendSwitch
        function LegendSwitchValueChanged(app, event)
            
            %value = app.LegendSwitch.Value;
            % find time series figure
            % hide/unhide legend
            if ~isempty(app.time_series_figs)
                tsaxes = findobj(app.time_series_figs.Children,'Type','Axes');
                legend(tsaxes,'toggle')
            end
            
        end

        % Value changed function: SurfaceVisibilitySwitch
        function SurfaceVisibilitySwitchValueChanged(app, event)
            
            SetStatusBusy(app)
            app.MessagesTextArea.Value = 'Toggling surface visibility ... ';
            
            if ishandle(app.colormesh2d_handle)
                if strcmp(app.SurfaceVisibilitySwitch.Value,'Show')
                    set(app.colormesh2d_handle,'Visible','on')
                else
                    set(app.colormesh2d_handle,'Visible','off')
                end
            end
            SetStatusReady(app)
            
        end

        % Value changed function: VectorVisibilitySwitch, 
        % VectorVisibilitySwitch_2
        function VectorVisibilitySwitchValueChanged(app, event)
            
            SetStatusBusy(app)
            app.MessagesTextArea.Value = 'Toggling vector visibility ... ';
            
            %value = app.HideShowSwitch.Value;
            if ishandle(app.vecplot_handle)
                if strcmp(app.VectorVisibilitySwitch.Value,'Show')
                    app.vecplot_handle.Visible='on';
                else
                    app.vecplot_handle.Visible='off';
                end
            end
            SetStatusReady(app)
            
        end

        % Button pushed function: SurfaceDeleteButton
        function SurfaceDeleteButtonPushed(app, event)
            
            delete(app.colormesh2d_handle)
            
        end

        % Button pushed function: VectorDeleteButton, 
        % VectorDeleteButton_2
        function VectorDeleteButtonPushed(app, event)
            
            delete(app.vecplot_handle)
            
        end

        % Value changed function: VectorsDropDown, VectorsDropDown_2
        function VectorsDropDownValueChanged(app, event)
            
            value = app.VectorsDropDown.Value;
            idx=strcmp(value,app.VectorsDropDown.Items);
            app.nc_vector=app.Datasets.Vectors{idx}.dataset;
            app.DrawFrame()
            
        end

        % Callback function
        function ScalarsoldDropDownValueChanged(app, event)
            
            value = app.ScalarsoldDropDown.Value;
            idx=strcmp(value,app.ScalarsoldDropDown.Items);
            app.nc_scalar=app.Datasets.Scalars{idx}.dataset;
            app.DrawFrame()
            
        end

        % Value changed function: ScalarsListBox
        function ScalarsListBoxValueChanged(app, event)
            
            value = app.ScalarsListBox.Value;
            idx=strcmp(value,app.ScalarsListBox.Items);
            app.nc_scalar=app.Datasets.Scalars{idx}.dataset;
            app.DrawFrame()
            
        end

        % Button pushed function: GotoViewMaxButton
        function GotoViewMaxButtonPushed(app, event)
            
        end

        % Value changed function: AxesLimitsEditField
        function AxesLimitsEditFieldValueChanged(app, event)
            
            app.AxisLims=str2num(app.AxesLimitsEditField.Value);
            SetAxisLims(app)
            
        end

        % Value changed function: GridVectorsCheckBox
        function GridVectorsCheckBoxValueChanged(app, event)
            %value = app.GridVectorsCheckBox.Value;
            disp('GridVectors: Not implemented yet.')

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ADCIRCAnimatorV11UIFigure = uifigure('Visible', 'off');
            app.ADCIRCAnimatorV11UIFigure.Name = 'ADCIRC Animator V1.1';
            app.ADCIRCAnimatorV11UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @ADCIRCAnimatorV11UIFigureWindowButtonUp, true);
            app.GridLayout = uigridlayout(app.ADCIRCAnimatorV11UIFigure);
            app.ScalarsPanel = uipanel(app.GridLayout);
            app.ScalarsPanel.Title = 'Scalars';
            app.GridLayout4 = uigridlayout(app.ScalarsPanel);
            app.ColormapLabel = uilabel(app.GridLayout4);
            app.ColormapLabel.Text = 'Colormap';
            app.ColormapDropDown = uidropdown(app.GridLayout4);
            app.ColormapDropDown.Items = {'jet', 'noaa_cmap', 'parula', 'GMT_jet_x20.act', ''};
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.ColormapDropDown.Value = 'jet';
            app.NumColorsLabel = uilabel(app.GridLayout4);
            app.NumColorsLabel.Text = 'Num Colors ';
            app.NumColorsEditField = uieditfield(app.GridLayout4, 'numeric');
            app.NumColorsEditField.Limits = [2 256];
            app.NumColorsEditField.RoundFractionalValues = 'on';
            app.NumColorsEditField.ValueDisplayFormat = '%d';
            app.NumColorsEditField.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.NumColorsEditField.Value = 24;
            app.MaxinViewTextAreaLabel = uilabel(app.GridLayout4);
            app.MaxinViewTextAreaLabel.Text = 'Max in View';
            app.MaxinViewTextArea = uitextarea(app.GridLayout4);
            app.MaxinViewTextArea.Editable = 'off';
            app.MaxinViewTextArea.Value = {'NaN'};
            app.MininViewTextAreaLabel = uilabel(app.GridLayout4);
            app.MininViewTextAreaLabel.Text = 'Min in View';
            app.MininViewTextArea = uitextarea(app.GridLayout4);
            app.MininViewTextArea.Editable = 'off';
            app.MininViewTextArea.Value = {'NaN'};
            app.ColorMinEditField_2Label = uilabel(app.GridLayout4);
            app.ColorMinEditField_2Label.Text = {'Color Min'; ''};
            app.ColorMinEditField_2 = uieditfield(app.GridLayout4, 'numeric');
            app.ColorMinEditField_2.ValueDisplayFormat = '%10.4g';
            app.ColorMinEditField_2.ValueChangedFcn = createCallbackFcn(app, @ColorMaxEditFieldValueChanged, true);
            app.ColorMinEditField_2.Value = -1;
            app.ColorMaxEditField_2Label = uilabel(app.GridLayout4);
            app.ColorMaxEditField_2Label.Text = 'Color Max';
            app.ColorMaxEditField_2 = uieditfield(app.GridLayout4, 'numeric');
            app.ColorMaxEditField_2.ValueDisplayFormat = '%10.4g';
            app.ColorMaxEditField_2.ValueChangedFcn = createCallbackFcn(app, @ColorMaxEditFieldValueChanged, true);
            app.ColorMaxEditField_2.Value = 1;
            app.AutoColorOffButton = uibutton(app.GridLayout4, 'state');
            app.AutoColorOffButton.ValueChangedFcn = createCallbackFcn(app, @AutoColorOffButtonValueChanged, true);
            app.AutoColorOffButton.Text = 'Auto Color Off';
            app.AutoColorOffButton.Value = true;
            app.SurfaceVisibilitySwitch = uiswitch(app.GridLayout4, 'toggle');
            app.SurfaceVisibilitySwitch.Items = {'Hide', 'Show'};
            app.SurfaceVisibilitySwitch.ValueChangedFcn = createCallbackFcn(app, @SurfaceVisibilitySwitchValueChanged, true);
            app.SurfaceVisibilitySwitch.Value = 'Show';
            app.SurfaceDeleteButton = uibutton(app.GridLayout4, 'push');
            app.SurfaceDeleteButton.ButtonPushedFcn = createCallbackFcn(app, @SurfaceDeleteButtonPushed, true);
            app.SurfaceDeleteButton.Text = 'Delete';
            app.ScalarsListBoxLabel = uilabel(app.GridLayout4);
            app.ScalarsListBoxLabel.Text = 'Scalars ';
            app.ScalarsListBox = uilistbox(app.GridLayout4);
            app.ScalarsListBox.Items = {'Scalar 1', 'Scalar 2', 'Scalar 3', 'Scalar 4'};
            app.ScalarsListBox.ValueChangedFcn = createCallbackFcn(app, @ScalarsListBoxValueChanged, true);
            app.ScalarsListBox.Value = 'Scalar 1';
            app.MessagesLabel = uilabel(app.GridLayout);
            app.MessagesLabel.Text = {'Messages :'; ''};
            app.CurrentSolutionTextAreaLabel = uilabel(app.GridLayout);
            app.CurrentSolutionTextAreaLabel.Text = 'Current Solution : ';
            app.MessagesTextArea = uitextarea(app.GridLayout);
            app.CurrentSolutionTextArea = uitextarea(app.GridLayout);
            app.GridPanel = uipanel(app.GridLayout);
            app.GridPanel.Title = 'Grid';
            app.GridLayout2 = uigridlayout(app.GridPanel);
            app.ElementsLabel = uilabel(app.GridLayout2);
            app.ElementsLabel.Text = 'Elements';
            app.ElementsSwitch = uiswitch(app.GridLayout2, 'toggle');
            app.ElementsSwitch.ValueChangedFcn = createCallbackFcn(app, @ElementsButtonValueChanged2, true);
            app.ElementsSwitch.Tooltip = {'toggle elements on/off'};
            app.ContoursLabel = uilabel(app.GridLayout2);
            app.ContoursLabel.Text = 'Contours';
            app.ContoursSwitch = uiswitch(app.GridLayout2, 'slider');
            app.ContoursSwitch.ValueChangedFcn = createCallbackFcn(app, @ContoursSwitchValueChanged, true);
            app.ContoursSwitch.Value = 'On';
            app.NumNodesLabel = uilabel(app.GridLayout2);
            app.NumNodesLabel.Text = 'Num Nodes';
            app.NumNodesSwitch = uiswitch(app.GridLayout2, 'toggle');
            app.NumNodesSwitch.ValueChangedFcn = createCallbackFcn(app, @NumNodesSwitchValueChanged, true);
            app.NumElemsLabel = uilabel(app.GridLayout2);
            app.NumElemsLabel.Text = 'Num Elems';
            app.NumElemsSwitch = uiswitch(app.GridLayout2, 'toggle');
            app.NumElemsSwitch.ValueChangedFcn = createCallbackFcn(app, @NumElemsSwitchValueChanged, true);
            app.NumBathyLabel = uilabel(app.GridLayout2);
            app.NumBathyLabel.Text = 'Num Bathy';
            app.NumBathySwitch = uiswitch(app.GridLayout2, 'toggle');
            app.NumBathySwitch.ValueChangedFcn = createCallbackFcn(app, @NumBathySwitchValueChanged, true);
            app.DepthValuesEditField = uieditfield(app.GridLayout2, 'text');
            app.DepthValuesEditField.ValueChangedFcn = createCallbackFcn(app, @DepthValuesEditFieldValueChanged, true);
            app.DepthValuesEditField.Tooltip = {'Enter topo/bathy values to contour.  Toggle Contours to erase.'};
            app.DepthValuesEditField.Value = '0 10  25  50  100 ';
            app.DepthValuesLabel = uilabel(app.GridLayout2);
            app.DepthValuesLabel.Text = 'Depth Values';
            app.ClearContoursButton = uibutton(app.GridLayout2, 'push');
            app.ClearContoursButton.ButtonPushedFcn = createCallbackFcn(app, @ClearContoursButtonPushed, true);
            app.ClearContoursButton.Text = 'Clear Contours';
            app.GotoGlobalMaxButton = uibutton(app.GridLayout2, 'push');
            app.GotoGlobalMaxButton.ButtonPushedFcn = createCallbackFcn(app, @GotoGlobalMaxButtonPushed, true);
            app.GotoGlobalMaxButton.Tooltip = {'Zoom to global maximum at current time level'};
            app.GotoGlobalMaxButton.Text = 'Goto Global Max';
            app.GotoViewMaxButton = uibutton(app.GridLayout2, 'push');
            app.GotoViewMaxButton.ButtonPushedFcn = createCallbackFcn(app, @GotoViewMaxButtonPushed, true);
            app.GotoViewMaxButton.Text = 'Goto View Max';
            app.TimeSeriesPanel = uipanel(app.GridLayout);
            app.TimeSeriesPanel.Title = 'Time Series';
            app.GridLayout5 = uigridlayout(app.TimeSeriesPanel);
            app.SampleSolutionCheckBox = uicheckbox(app.GridLayout5);
            app.SampleSolutionCheckBox.ValueChangedFcn = createCallbackFcn(app, @SampleSolutionCheckBoxValueChanged, true);
            app.SampleSolutionCheckBox.Text = ' Sample Solution';
            app.LegendLabel = uilabel(app.GridLayout5);
            app.LegendLabel.Text = 'Legend : ';
            app.LegendSwitch = uiswitch(app.GridLayout5, 'toggle');
            app.LegendSwitch.Items = {'Hide', 'Show'};
            app.LegendSwitch.ValueChangedFcn = createCallbackFcn(app, @LegendSwitchValueChanged, true);
            app.LegendSwitch.Value = 'Show';
            app.StatusLabel = uilabel(app.GridLayout);
            app.StatusLabel.Text = 'Status :';
            app.FrameControlPanel = uipanel(app.GridLayout);
            app.FrameControlPanel.Title = 'Frame Control';
            app.BackwardButton = uibutton(app.FrameControlPanel, 'push');
            app.BackwardButton.ButtonPushedFcn = createCallbackFcn(app, @BackwardButtonPushed, true);
            app.BackwardButton.Icon = 'Left_64.png';
            app.BackwardButton.Enable = 'off';
            app.BackwardButton.Text = '';
            app.ForwardButton = uibutton(app.FrameControlPanel, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Icon = 'Right_64.png';
            app.ForwardButton.Enable = 'off';
            app.ForwardButton.Text = '';
            app.GOButton = uibutton(app.FrameControlPanel, 'state');
            app.GOButton.ValueChangedFcn = createCallbackFcn(app, @GOButtonValueChanged, true);
            app.GOButton.Enable = 'off';
            app.GOButton.Text = 'GO';
            app.TimeStrideLabel = uilabel(app.FrameControlPanel);
            app.TimeStrideLabel.Text = 'Time Stride';
            app.TimeStrideEditField = uieditfield(app.FrameControlPanel, 'numeric');
            app.TimeStrideEditField.Limits = [1 Inf];
            app.TimeStrideEditField.RoundFractionalValues = 'on';
            app.TimeStrideEditField.ValueDisplayFormat = '%d';
            app.TimeStrideEditField.Value = 1;
            app.CurrentIndexLabel = uilabel(app.FrameControlPanel);
            app.CurrentIndexLabel.Text = 'Current Index';
            app.CurrentIndexEditField = uieditfield(app.FrameControlPanel, 'numeric');
            app.CurrentIndexEditField.Limits = [1 Inf];
            app.CurrentIndexEditField.RoundFractionalValues = 'on';
            app.CurrentIndexEditField.ValueDisplayFormat = '%d';
            app.CurrentIndexEditField.ValueChangedFcn = createCallbackFcn(app, @CurrentIndexEditFieldValueChanged, true);
            app.CurrentIndexEditField.Value = 1;
            app.TImeIndexPanel = uipanel(app.GridLayout);
            app.TImeIndexPanel.Title = 'Time Index';
            app.SliderLabel = uilabel(app.TImeIndexPanel);
            app.SliderLabel.Text = {''; ''};
            app.Slider = uislider(app.TImeIndexPanel);
            app.Slider.Limits = [1 100];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @TimeSliderIndexEditFieldValueChanged, true);
            app.Slider.Value = 1;
            app.InfoPanel = uipanel(app.GridLayout);
            app.InfoPanel.Title = 'Info';
            app.GridNameTextAreaLabel = uilabel(app.InfoPanel);
            app.GridNameTextAreaLabel.Text = 'Grid Name :';
            app.GridNameTextArea = uitextarea(app.InfoPanel);
            app.GridNameTextArea.Editable = 'off';
            app.GridNameTextArea.Value = {'<>'};
            app.ElementsTextAreaLabel = uilabel(app.InfoPanel);
            app.ElementsTextAreaLabel.Text = '# Elements :';
            app.ElementsTextArea = uitextarea(app.InfoPanel);
            app.ElementsTextArea.Editable = 'off';
            app.ElementsTextArea.Value = {'<>'};
            app.NodesTextAreaLabel = uilabel(app.InfoPanel);
            app.NodesTextAreaLabel.Text = '# Nodes :';
            app.NodesTextArea = uitextarea(app.InfoPanel);
            app.NodesTextArea.Editable = 'off';
            app.NodesTextArea.Value = {'<>'};
            app.OutputTimeStepEditFieldLabel = uilabel(app.InfoPanel);
            app.OutputTimeStepEditFieldLabel.Text = 'Output Time Step :';
            app.OutputTimeStepEditField = uieditfield(app.InfoPanel, 'text');
            app.OutputTimeStepEditField.Editable = 'off';
            app.OutputTimeStepEditField.Value = '<>';
            app.RNDAYTextAreaLabel = uilabel(app.InfoPanel);
            app.RNDAYTextAreaLabel.Text = 'RNDAY :';
            app.RNDAYTextArea = uitextarea(app.InfoPanel);
            app.RNDAYTextArea.Value = {'<>'};
            app.StatusLamp = uilamp(app.GridLayout);
            app.StuffPanel = uipanel(app.GridLayout);
            app.StuffPanel.Title = 'Stuff';
            app.GridLayout6 = uigridlayout(app.StuffPanel);
            app.LOADButton = uibutton(app.GridLayout6, 'push');
            app.LOADButton.ButtonPushedFcn = createCallbackFcn(app, @LOADButtonPushed, true);
            app.LOADButton.Text = 'LOAD';
            app.AxisEqualButton = uibutton(app.GridLayout6, 'push');
            app.AxisEqualButton.ButtonPushedFcn = createCallbackFcn(app, @AxisEqualButtonPushed, true);
            app.AxisEqualButton.Text = 'Axis Equal';
            app.AxisTightButton = uibutton(app.GridLayout6, 'push');
            app.AxisTightButton.ButtonPushedFcn = createCallbackFcn(app, @AxisTightButtonPushed, true);
            app.AxisTightButton.Text = 'Axis Tight';
            app.DrawFrameButton = uibutton(app.GridLayout6, 'push');
            app.DrawFrameButton.ButtonPushedFcn = createCallbackFcn(app, @DrawFrameButtonPushed, true);
            app.DrawFrameButton.Text = 'Draw Frame';
            app.GMapButton = uibutton(app.GridLayout6, 'push');
            app.GMapButton.ButtonPushedFcn = createCallbackFcn(app, @GMapButtonValueChanged, true);
            app.GMapButton.Text = 'GMap';
            app.BrowserButton = uibutton(app.GridLayout6, 'push');
            app.BrowserButton.ButtonPushedFcn = createCallbackFcn(app, @BrowserButtonPushed, true);
            app.BrowserButton.Text = {'Browser'; ''};
            app.testVectorsPanel = uipanel(app.GridLayout);
            app.testVectorsPanel.Title = 'testVectors';
            app.testVectorsPanel.Visible = 'off';
            app.AxesLimitsPanel = uipanel(app.GridLayout);
            app.AxesLimitsPanel.Title = 'Axes';
            app.AxesLimitsEditFieldLabel = uilabel(app.AxesLimitsPanel);
            app.AxesLimitsEditFieldLabel.Text = 'Axes Limits : ';
            app.AxesLimitsEditField = uieditfield(app.AxesLimitsPanel, 'text');
            app.AxesLimitsEditField.ValueChangedFcn = createCallbackFcn(app, @AxesLimitsEditFieldValueChanged, true);
            app.AxesLimitsEditField.Value = '0 0 0 0';
            app.VectorsTabGroup = uitabgroup(app.GridLayout);
            app.VectorsTabGroup.Visible = 'off';
            app.VectorsTab = uitab(app.VectorsTabGroup);
            app.VectorsTab.Title = 'Vectors';
            app.VectorsPanel_1 = uipanel(app.VectorsTab);
            app.GridLayout3_2 = uigridlayout(app.VectorsPanel_1);
            app.ScaleEditFieldLabel_2 = uilabel(app.GridLayout3_2);
            app.ScaleEditFieldLabel_2.Text = {'Scale'; ''};
            app.ScaleEditField_2 = uieditfield(app.GridLayout3_2, 'text');
            app.ScaleEditField_2.ValueChangedFcn = createCallbackFcn(app, @ScaleEditFieldValueChanged, true);
            app.ScaleEditField_2.Value = '1';
            app.StrideEditFieldLabel_2 = uilabel(app.GridLayout3_2);
            app.StrideEditFieldLabel_2.Text = 'Stride';
            app.StrideEditField_2 = uieditfield(app.GridLayout3_2, 'text');
            app.StrideEditField_2.ValueChangedFcn = createCallbackFcn(app, @ScaleEditFieldValueChanged, true);
            app.StrideEditField_2.Value = '10';
            app.VectorColorLabel_2 = uilabel(app.GridLayout3_2);
            app.VectorColorLabel_2.Text = 'Vector Color';
            app.VectorColorDropDown_2 = uidropdown(app.GridLayout3_2);
            app.VectorColorDropDown_2.Items = {'Black', 'Red', 'White', 'Blue'};
            app.VectorColorDropDown_2.ItemsData = {'k', 'r', 'w', 'b'};
            app.VectorColorDropDown_2.ValueChangedFcn = createCallbackFcn(app, @VectorColorDropDownValueChanged, true);
            app.VectorColorDropDown_2.Value = 'k';
            app.MaxinViewTextArea_2Label_2 = uilabel(app.GridLayout3_2);
            app.MaxinViewTextArea_2Label_2.Enable = 'off';
            app.MaxinViewTextArea_2Label_2.Text = 'Max in View';
            app.MaxinViewTextArea_3 = uitextarea(app.GridLayout3_2);
            app.MaxinViewTextArea_3.Editable = 'off';
            app.MaxinViewTextArea_3.Enable = 'off';
            app.MaxinViewTextArea_3.Value = {'NaN'};
            app.VectorVisibilitySwitch_2 = uiswitch(app.GridLayout3_2, 'toggle');
            app.VectorVisibilitySwitch_2.Items = {'Hide', 'Show'};
            app.VectorVisibilitySwitch_2.ValueChangedFcn = createCallbackFcn(app, @VectorVisibilitySwitchValueChanged, true);
            app.VectorVisibilitySwitch_2.Value = 'Show';
            app.VectorDeleteButton_2 = uibutton(app.GridLayout3_2, 'push');
            app.VectorDeleteButton_2.ButtonPushedFcn = createCallbackFcn(app, @VectorDeleteButtonPushed, true);
            app.VectorDeleteButton_2.Text = 'Delete';
            app.VectorsDropDown_2Label = uilabel(app.GridLayout3_2);
            app.VectorsDropDown_2Label.Text = 'Vectors ';
            app.VectorsDropDown_2 = uidropdown(app.GridLayout3_2);
            app.VectorsDropDown_2.Items = {'Vector 1', 'Vector 2', 'Vector 3', 'Vector 4'};
            app.VectorsDropDown_2.ValueChangedFcn = createCallbackFcn(app, @VectorsDropDownValueChanged, true);
            app.VectorsDropDown_2.Value = 'Vector 1';
            app.GriddedTab = uitab(app.VectorsTabGroup);
            app.GriddedTab.Title = 'Gridded';
            app.Panel_2 = uipanel(app.GriddedTab);
            app.GridVectorsCheckBox = uicheckbox(app.Panel_2);
            app.GridVectorsCheckBox.ValueChangedFcn = createCallbackFcn(app, @GridVectorsCheckBoxValueChanged, true);
            app.GridVectorsCheckBox.Text = 'Grid Vectors';
            app.ResolutiondegEditFieldLabel = uilabel(app.Panel_2);
            app.ResolutiondegEditFieldLabel.Text = 'Resolution (deg)';
            app.ResolutiondegEditField = uieditfield(app.Panel_2, 'numeric');
            app.ResolutiondegEditField.Value = 1;
            app.ComputeButton = uibutton(app.Panel_2, 'state');
            app.ComputeButton.Text = 'Compute';
            app.VectorsPanel = uipanel(app.GridLayout);
            app.VectorsPanel.Title = 'Vectors';
            app.GridLayout3 = uigridlayout(app.VectorsPanel);
            app.ScaleEditFieldLabel = uilabel(app.GridLayout3);
            app.ScaleEditFieldLabel.Text = {'Scale'; ''};
            app.ScaleEditField = uieditfield(app.GridLayout3, 'text');
            app.ScaleEditField.ValueChangedFcn = createCallbackFcn(app, @ScaleEditFieldValueChanged, true);
            app.ScaleEditField.Value = '1';
            app.StrideEditFieldLabel = uilabel(app.GridLayout3);
            app.StrideEditFieldLabel.Text = 'Stride';
            app.StrideEditField = uieditfield(app.GridLayout3, 'text');
            app.StrideEditField.ValueChangedFcn = createCallbackFcn(app, @ScaleEditFieldValueChanged, true);
            app.StrideEditField.Value = '10';
            app.VectorColorLabel = uilabel(app.GridLayout3);
            app.VectorColorLabel.Text = 'Vector Color';
            app.VectorColorDropDown = uidropdown(app.GridLayout3);
            app.VectorColorDropDown.Items = {'Black', 'Red', 'White', 'Blue'};
            app.VectorColorDropDown.ItemsData = {'k', 'r', 'w', 'b'};
            app.VectorColorDropDown.ValueChangedFcn = createCallbackFcn(app, @VectorColorDropDownValueChanged, true);
            app.VectorColorDropDown.Value = 'k';
            app.MaxinViewTextArea_2Label = uilabel(app.GridLayout3);
            app.MaxinViewTextArea_2Label.Text = 'Max in View';
            app.MaxinViewTextArea_2 = uitextarea(app.GridLayout3);
            app.MaxinViewTextArea_2.Editable = 'off';
            app.MaxinViewTextArea_2.Value = {'NaN'};
            app.VectorVisibilitySwitch = uiswitch(app.GridLayout3, 'toggle');
            app.VectorVisibilitySwitch.Items = {'Hide', 'Show'};
            app.VectorVisibilitySwitch.ValueChangedFcn = createCallbackFcn(app, @VectorVisibilitySwitchValueChanged, true);
            app.VectorVisibilitySwitch.Value = 'Show';
            app.VectorDeleteButton = uibutton(app.GridLayout3, 'push');
            app.VectorDeleteButton.ButtonPushedFcn = createCallbackFcn(app, @VectorDeleteButtonPushed, true);
            app.VectorDeleteButton.Text = 'Delete';
            app.VectorsLabel = uilabel(app.GridLayout3);
            app.VectorsLabel.Text = 'Vectors ';
            app.VectorsDropDown = uidropdown(app.GridLayout3);
            app.VectorsDropDown.Items = {'Vector 1', 'Vector 2', 'Vector 3', 'Vector 4'};
            app.VectorsDropDown.ValueChangedFcn = createCallbackFcn(app, @VectorsDropDownValueChanged, true);
            app.VectorsDropDown.Value = 'Vector 1';
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'NaN')
            xlabel(app.UIAxes, 'Lon/X')
            ylabel(app.UIAxes, 'Lat/Y')
            % Show the figure after all components are created
            app.ADCIRCAnimatorV11UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = adcircAnimator(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ADCIRCAnimatorV11UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ADCIRCAnimatorV11UIFigure)
        end
    end
end